classdef VRM_Inverse_Kinematics_Algs_VACULIK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        L1EditFieldLabel                matlab.ui.control.Label
        L1EditField                     matlab.ui.control.NumericEditField
        L2EditFieldLabel                matlab.ui.control.Label
        L2EditField                     matlab.ui.control.NumericEditField
        GeneratedanglesEditFieldLabel   matlab.ui.control.Label
        GeneratedanglesEditField        matlab.ui.control.NumericEditField
        Howmanyx_targety_targetpointsshouldbegeneratedLabel  matlab.ui.control.Label
        jointroboticarmparametersLabel  matlab.ui.control.Label
        PointsgeneratorAnglesforforwardkinematicsLabel  matlab.ui.control.Label
        InversekinematicsparametersLabel  matlab.ui.control.Label
        MaxiterationEditFieldLabel      matlab.ui.control.Label
        MaxiterationEditField           matlab.ui.control.NumericEditField
        perpointLabel                   matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        INPUTLabel                      matlab.ui.control.Label
        PrecisionEditFieldLabel         matlab.ui.control.Label
        PrecisionEditField              matlab.ui.control.NumericEditField
        UpdateresultsCheckBox           matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        CcdAvgErrorEditField            matlab.ui.control.NumericEditField
        FabrikAvgErrorEditField         matlab.ui.control.NumericEditField
        CcdAvgItersEditField            matlab.ui.control.NumericEditField
        FabrikAvgItersEditField         matlab.ui.control.NumericEditField
        CcdSoulutionsFoundEditField     matlab.ui.control.NumericEditField
        FabrikSoulutionsFoundEditField  matlab.ui.control.NumericEditField
        DEAvgErrorEditField             matlab.ui.control.NumericEditField
        DEAvgItersEditField             matlab.ui.control.NumericEditField
        DESoulutionsFoundEditField      matlab.ui.control.NumericEditField
        AvgerroroffoundsoulutionsLabel  matlab.ui.control.Label
        AvgiterationsoffoundsoulutionsLabel  matlab.ui.control.Label
        SoulutionfoundLabel             matlab.ui.control.Label
        CCDLabel                        matlab.ui.control.Label
        FABRIKLabel                     matlab.ui.control.Label
        DifferentialevolutionLabel      matlab.ui.control.Label
        OUTPUTLabel                     matlab.ui.control.Label
        LukVaculkID211190Label          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = false;
            app.RunButton.Text = 'Processing...';
            
            %%Zero all
            app.DESoulutionsFoundEditField.Value = 0;
            app.DEAvgItersEditField.Value = 0; 
            app.DEAvgErrorEditField.Value = 0; 
            app.FabrikSoulutionsFoundEditField.Value = 0;
            app.FabrikAvgItersEditField.Value = 0; 
            app.FabrikAvgErrorEditField.Value = 0; 
            app.CcdSoulutionsFoundEditField.Value = 0;
            app.CcdAvgItersEditField.Value = 0; 
            app.CcdAvgErrorEditField.Value = 0; 
            
            drawnow; % Force UI to update
            
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;  
            numberOfPoints=app.GeneratedanglesEditField.Value; 
            max_iter=app.MaxiterationEditField.Value;
            par_eps=app.PrecisionEditField.Value;
            updateResults = app.UpdateresultsCheckBox.Value;
            
            %%Init variables
            soulutionDeFound = 0;
            soulutionFabrikFound = 0;
            soulutionCcdFound = 0;
            errorDE = zeros(1, numberOfPoints);
            iterationDE = zeros(1, numberOfPoints);
            errorFabrik = zeros(1, numberOfPoints);
            iterationFabrik = zeros(1, numberOfPoints);
            errorCcd = zeros(1, numberOfPoints);
            iterationCcd = zeros(1, numberOfPoints);
            
            %Make "i" calls of main IK function
            for i=1:numberOfPoints
                
                [iterDE, iterFabrik, iterCCD,...
                difDE, difFabrik, difCcd,...
                 unsuccesfullDE, unsuccesfullFabrik, unsuccesfullCCD]...
                = InverseKinematicsAlgs(L1,L2, par_eps, max_iter);
                
                if (unsuccesfullDE ==0)
                    soulutionDeFound = soulutionDeFound+1;
                    errorDE(i)=difDE;
                    iterationDE(i)=iterDE;
                end
                if (unsuccesfullFabrik ==0)
                    soulutionFabrikFound = soulutionFabrikFound+1;
                    errorFabrik(i)=difFabrik;
                    iterationFabrik(i)=iterFabrik;
                end
                if (unsuccesfullCCD ==0)
                    soulutionCcdFound = soulutionCcdFound+1;
                    errorCcd(i)=difCcd;
                    iterationCcd(i)=iterCCD;
                end
 

                %%update fields
                if soulutionDeFound >0
                    app.DESoulutionsFoundEditField.Value = soulutionDeFound;
                    app.DEAvgItersEditField.Value = (sum(iterationDE) / soulutionDeFound ); 
                    app.DEAvgErrorEditField.Value = (sum(errorDE) / soulutionDeFound ); 
                end
                if soulutionFabrikFound >0
                    app.FabrikSoulutionsFoundEditField.Value = soulutionFabrikFound;
                    app.FabrikAvgItersEditField.Value = (sum(iterationFabrik) / soulutionFabrikFound ); 
                    app.FabrikAvgErrorEditField.Value = (sum(errorFabrik) / soulutionFabrikFound ); 
                end
                if soulutionCcdFound >0
                    app.CcdSoulutionsFoundEditField.Value = soulutionCcdFound;
                    app.CcdAvgItersEditField.Value = (sum(iterationCcd) / soulutionCcdFound ); 
                    app.CcdAvgErrorEditField.Value = (sum(errorCcd) / soulutionCcdFound ); 
                end
                
                if (updateResults==1)
                    drawnow; % Force UI to update
                end
                
           end
            
           
         app.RunButton.Enable = true;   
         app.RunButton.Text = 'Run';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {505, 505};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {299, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.L1EditFieldLabel = uilabel(app.LeftPanel);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.L1EditField.Value = 2;
            app.L2EditFieldLabel = uilabel(app.LeftPanel);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.L2EditField.Value = 3;
            app.GeneratedanglesEditFieldLabel = uilabel(app.LeftPanel);
            app.GeneratedanglesEditFieldLabel.Text = 'Generated angles';
            app.GeneratedanglesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GeneratedanglesEditField.Value = 80;
            app.Howmanyx_targety_targetpointsshouldbegeneratedLabel = uilabel(app.LeftPanel);
            app.Howmanyx_targety_targetpointsshouldbegeneratedLabel.Text = '* How many [x_target,y_target] points should be generated ';
            app.jointroboticarmparametersLabel = uilabel(app.LeftPanel);
            app.jointroboticarmparametersLabel.Text = '2-joint robotic arm parameters';
            app.PointsgeneratorAnglesforforwardkinematicsLabel = uilabel(app.LeftPanel);
            app.PointsgeneratorAnglesforforwardkinematicsLabel.Text = 'Points generator (Angles for forward kinematics)';
            app.InversekinematicsparametersLabel = uilabel(app.LeftPanel);
            app.InversekinematicsparametersLabel.Text = 'Inverse kinematics parameters';
            app.MaxiterationEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxiterationEditFieldLabel.Text = 'Max iteration';
            app.MaxiterationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxiterationEditField.Value = 1000;
            app.perpointLabel = uilabel(app.LeftPanel);
            app.perpointLabel.Text = '/per point';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.INPUTLabel = uilabel(app.LeftPanel);
            app.INPUTLabel.Text = 'INPUT';
            app.PrecisionEditFieldLabel = uilabel(app.LeftPanel);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PrecisionEditField.Value = 1e-05;
            app.UpdateresultsCheckBox = uicheckbox(app.LeftPanel);
            app.UpdateresultsCheckBox.Text = 'Update results after every finished point';
            app.UpdateresultsCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.CcdAvgErrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.CcdAvgErrorEditField.Editable = 'off';
            app.FabrikAvgErrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.FabrikAvgErrorEditField.Editable = 'off';
            app.CcdAvgItersEditField = uieditfield(app.RightPanel, 'numeric');
            app.CcdAvgItersEditField.Editable = 'off';
            app.FabrikAvgItersEditField = uieditfield(app.RightPanel, 'numeric');
            app.FabrikAvgItersEditField.Editable = 'off';
            app.CcdSoulutionsFoundEditField = uieditfield(app.RightPanel, 'numeric');
            app.CcdSoulutionsFoundEditField.Editable = 'off';
            app.FabrikSoulutionsFoundEditField = uieditfield(app.RightPanel, 'numeric');
            app.FabrikSoulutionsFoundEditField.Editable = 'off';
            app.DEAvgErrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.DEAvgErrorEditField.Editable = 'off';
            app.DEAvgItersEditField = uieditfield(app.RightPanel, 'numeric');
            app.DEAvgItersEditField.Editable = 'off';
            app.DESoulutionsFoundEditField = uieditfield(app.RightPanel, 'numeric');
            app.DESoulutionsFoundEditField.Editable = 'off';
            app.AvgerroroffoundsoulutionsLabel = uilabel(app.RightPanel);
            app.AvgerroroffoundsoulutionsLabel.Text = {'Avg. error of '; 'found soulutions'};
            app.AvgiterationsoffoundsoulutionsLabel = uilabel(app.RightPanel);
            app.AvgiterationsoffoundsoulutionsLabel.Text = {'Avg. iterations of '; 'found soulutions'};
            app.SoulutionfoundLabel = uilabel(app.RightPanel);
            app.SoulutionfoundLabel.Text = 'Soulution found';
            app.CCDLabel = uilabel(app.RightPanel);
            app.CCDLabel.Text = 'CCD';
            app.FABRIKLabel = uilabel(app.RightPanel);
            app.FABRIKLabel.Text = 'FABRIK';
            app.DifferentialevolutionLabel = uilabel(app.RightPanel);
            app.DifferentialevolutionLabel.Text = 'Differential evolution';
            app.OUTPUTLabel = uilabel(app.RightPanel);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.LukVaculkID211190Label = uilabel(app.RightPanel);
            app.LukVaculkID211190Label.Enable = 'off';
            app.LukVaculkID211190Label.Text = 'Lukáš Vaculík, ID:211190';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VRM_Inverse_Kinematics_Algs_VACULIK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end